classdef finalapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LaptopCameraInputDropDown       matlab.ui.control.DropDown
        LaptopCameraInputDropDownLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        InputValuesTab                  matlab.ui.container.Tab
        FocallengthinxaxisTextArea      matlab.ui.control.TextArea
        FocallengthinxaxisTextAreaLabel  matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        FocallengthinyaxisTextArea      matlab.ui.control.TextArea
        FocallengthinyaxisTextAreaLabel  matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        DSimulationTab                  matlab.ui.container.Tab
        SimulateButton                  matlab.ui.control.Button
        UIAxes4                         matlab.ui.control.UIAxes
        XTab                            matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        YTab                            matlab.ui.container.Tab
        UIAxes_2                        matlab.ui.control.UIAxes
        ZTab                            matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        RotationTab                     matlab.ui.container.Tab
        Lamp                            matlab.ui.control.Lamp
        LampLabel                       matlab.ui.control.Label
    end

    
    properties (Access = private)
        cam; %camera object
        %x y and z values to be used for plotting
        z_cm_values;
        y_cm_values;
        x_cm_values;

    end
    
    methods (Access = private)
        
        function results = func(app)
           
            %xval = app.z_cm

           %yval =
           %zval =
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.cam = webcam;
            frame = snapshot(app.cam);
            im = image(app.UIAxes3, zeros(size(frame),'uint8'));
            axis(app.UIAxes3,'image');

            preview(app.cam,im);

%            pause;

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
           x=-20:0.1:20;
           y=sin(x);
           plot(app.UIAxes, x, y);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputValuesTab = uitab(app.TabGroup);
            app.InputValuesTab.Title = 'Input Values';
            app.UIAxes3 = uiaxes(app.InputValuesTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.FocallengthinyaxisTextAreaLabel = uilabel(app.InputValuesTab);
            app.FocallengthinyaxisTextAreaLabel.Text = 'Focal length in y-axis';
            app.FocallengthinyaxisTextArea = uitextarea(app.InputValuesTab);
            app.FocallengthinyaxisTextArea.Value = {'2603.63735'};
            app.StartButton = uibutton(app.InputValuesTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.FocallengthinxaxisTextAreaLabel = uilabel(app.InputValuesTab);
            app.FocallengthinxaxisTextAreaLabel.Text = 'Focal length in x-axis';
            app.FocallengthinxaxisTextArea = uitextarea(app.InputValuesTab);
            app.FocallengthinxaxisTextArea.Value = {'2603.90493'};
            app.DSimulationTab = uitab(app.TabGroup);
            app.DSimulationTab.Title = '3D Simulation';
            app.UIAxes4 = uiaxes(app.DSimulationTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.SimulateButton = uibutton(app.DSimulationTab, 'push');
            app.SimulateButton.Text = 'Simulate';
            app.XTab = uitab(app.TabGroup);
            app.XTab.Title = 'X';
            app.UIAxes = uiaxes(app.XTab);
            title(app.UIAxes, 'X Distance from Target')
            xlabel(app.UIAxes, 'Time (Seconds)')
            ylabel(app.UIAxes, 'X (cm)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.YTab = uitab(app.TabGroup);
            app.YTab.Title = 'Y';
            app.UIAxes_2 = uiaxes(app.YTab);
            title(app.UIAxes_2, 'Y Distance from Target')
            xlabel(app.UIAxes_2, 'Time (Seconds)')
            ylabel(app.UIAxes_2, 'Y (cm)')
            zlabel(app.UIAxes_2, 'Z')
            app.ZTab = uitab(app.TabGroup);
            app.ZTab.Title = 'Z';
            app.UIAxes2 = uiaxes(app.ZTab);
            title(app.UIAxes2, 'Depth Distance from Target')
            xlabel(app.UIAxes2, 'Time (Seconds)')
            ylabel(app.UIAxes2, 'Z (cm)')
            zlabel(app.UIAxes2, 'Z')
            app.RotationTab = uitab(app.TabGroup);
            app.RotationTab.Title = 'Rotation';
            app.LaptopCameraInputDropDownLabel = uilabel(app.UIFigure);
            app.LaptopCameraInputDropDownLabel.Text = 'Laptop Camera Input';
            app.LaptopCameraInputDropDown = uidropdown(app.UIFigure);
            app.LaptopCameraInputDropDown.Items = {'Cameron', 'Hamish', 'Sarah'};
            app.LaptopCameraInputDropDown.Value = 'Cameron';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finalapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end